classdef J1Pr43 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        live                     matlab.ui.control.UIAxes
        StartButton              matlab.ui.control.Button
        RoundnessEditFieldLabel  matlab.ui.control.Label
        RoundnessEditField       matlab.ui.control.NumericEditField
        PeakCountEditFieldLabel  matlab.ui.control.Label
        PeakCountEditField       matlab.ui.control.EditField
        selectedArea             matlab.ui.control.UIAxes
        Jplot                    matlab.ui.control.UIAxes
        background               matlab.ui.control.UIAxes
        filteredOutput           matlab.ui.control.UIAxes
        LeaterEditFieldLabel     matlab.ui.control.Label
        LeaterEditField          matlab.ui.control.EditField
        TextEditFieldLabel       matlab.ui.control.Label
        TextEditField            matlab.ui.control.EditField
    end


    properties (Access = public)
        Property % Description
        cam = webcam(1);
        x=0;
        y=0;
        imBackground = 0;
        im=0;
        IM1 = 0;
        IM2 = 0
        IM3 = 0;
        SE = 0;
        REG = 0;
        CEN = 0;
        B = 0;
        L = 0;
        N = 0;
        A = 0;
        RND = 0;
        PER = 0;
        ARE = 0;
        BND = 0;
        BNDx = 0;
        BNDy = 0;
        pkoffset = 0;
        pks = 0;
        pkNo = 0;
        region = [20, 150, 200, 200];
        pkNo_STR = '';
        CHAR_STR = '';
        sen_STR = '';
        pos = 1;
        la = 0;
        ditect = 0;
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             
            
            while(app.y<5)
                app.imBackground = snapshot(app.cam);
                app.IM1 = imcrop(app.imBackground,app.region);
                imshow(app.IM1, 'Parent', app.background);
                pause(1);
                app.IM1 = rgb2gray(app.IM1); 
                app.y = app.y+1;
            end
            
            while(app.x<200)
                app.im = snapshot(app.cam);
                imOut = insertObjectAnnotation(app.im,'rectangle',app.region,'Region','Color','blue');
                imshow(imOut, 'Parent', app.live);
                app.IM2 = imcrop(app.im,app.region);
                imshow(app.IM2, 'Parent', app.selectedArea);
                app.IM2 = rgb2gray(app.IM2);
                app.IM3 = app.IM2 - app.IM1;
                app.IM3 = im2bw(app.IM3, 0.1);
                app.IM3 = imfill(app.IM3, 'holes');
                app.SE = strel('square',5);
                app.IM3 = imopen(app.IM3, app.SE);
                app.IM3 = medfilt2(app.IM3, [5 5]);                                                  
                
                imshow(app.IM3, 'Parent', app.filteredOutput);
                
                app.IM3 = flipdim(app.IM3,1);
                app.REG=regionprops(app.IM3,'all');
                app.CEN = cat(1, app.REG.Centroid);
                [app.B, app.L, app.N, app.A] = bwboundaries(app.IM3,'noholes');
                
                for k =1:length(app.B)
                    app.PER = app.REG(k).Perimeter;
                    app.ARE = app.REG(k).Area;
                    app.RND = (4*pi*app.ARE)/(app.PER^2);
                    app.BND = app.B{k};
                    app.BNDx = app.BND(:,2);
                    app.BNDy = app.BND(:,1);
                    app.pkoffset = app.CEN(:,2)+.5*(app.CEN(:,2));
                    app.pks = findpeaks(app.BNDy);
                    app.pkNo = size(app.pks,1);
                    app.pkNo_STR = sprintf('%2.0f',app.pkNo);
                end
                
                plot(app.Jplot,app.BNDx,app.BNDy);
                
                %if app.RND >0.19 && app.RND < 0.24 && app.pkNo ==3
                    %app.CHAR_STR = 'W';
                %elseif app.RND >0.44 && app.RND < 0.47  && app.pkNo ==1
                    %app.CHAR_STR = 'O';
                %elseif app.RND >0.37 && app.RND < 0.40 && app.pkNo ==2
                    %app.CHAR_STR = 'R';
                %elseif app.RND >0.40 && app.RND < 0.43 && app.pkNo == 3
                    %app.CHAR_STR = 'D';
                %else
                    %app.CHAR_STR = 'not identified';
                %end
                
                if app.RND >0.65 && app.RND < 0.81 && app.pkNo >3 && app.pkNo <6
                    app.CHAR_STR = 'A';
                    app.la = 1;
                    app.ditect = 1;
                elseif app.RND >0.3 && app.RND < 0.4  && app.pkNo >2 && app.pkNo <5
                    app.CHAR_STR = 'W';
                    app.la = 2;
                    app.ditect = 1;
                elseif app.RND >0.54 && app.RND < 0.65 && app.pkNo >0 && app.pkNo <3
                    app.CHAR_STR = 'I';
                    app.la = 3;
                    app.ditect = 1;
                elseif app.RND >0.54 && app.RND < 0.65 && app.pkNo >2 && app.pkNo <5
                    app.CHAR_STR = 'D';
                    app.la = 4;
                    app.ditect = 1;
                elseif app.RND >0.7 && app.RND < 0.8 app.pkNo >1 && app.pkNo <4
                    app.CHAR_STR = 'N';
                    app.la = 5;
                    app.ditect = 1;
                elseif app.RND >0.8 && app.RND < 0.85 app.pkNo = 1
                    app.CHAR_STR = 'T';
                    app.la = 6;
                    app.ditect = 1;
                else
                    app.CHAR_STR = 'not identified';
                end
                
                % Dad I did it
                if app.pos == 1 && app.la == 4 && app.ditect == 1
                    app.ditect = 0;
                    app.pos = 2;
                    app.sen_STR = 'D';
                end    
                
                if app.pos == 2 && app.la == 1 && app.ditect == 1
                    app.ditect = 0;
                    app.pos = 3;
                    app.sen_STR = 'DA';
                end 
                
                if app.pos == 3 && app.la == 4 && app.ditect == 1
                    app.ditect = 0;
                    app.pos = 4;
                    app.sen_STR = 'DAD';
                end 
                
                if app.pos == 4 && app.la == 3 && app.ditect == 1
                    app.ditect = 0;
                    app.pos = 5;
                    app.sen_STR = 'DAD I';
                end
                
                if app.pos == 5 && app.la == 2 && app.ditect == 1
                    app.ditect = 0;
                    app.pos = 6;
                    app.sen_STR = 'DAD I W';
                end 
                
                if app.pos == 6 && app.la == 3 && app.ditect == 1
                    app.ditect = 0;
                    app.pos = 7;
                    app.sen_STR = 'DAD I WI';
                end 
                
                if app.pos == 7 && app.la == 5 && app.ditect == 1
                    app.ditect = 0;
                    app.pos = 8;
                    app.sen_STR = 'DAD I WIN';
                end
                
                if app.pos == 8 && app.la == 3 && app.ditect == 1
                    app.ditect = 0;
                    app.pos = 9;
                    app.sen_STR = 'DAD I WIN I';
                end
                
                if app.pos == 9 && app.la == 6 && app.ditect == 1
                    app.ditect = 0;
                    app.pos = 10;
                    app.sen_STR = 'DAD I WIN IT';
                end
                 
                app.LeaterEditField.Value = app.CHAR_STR;
                app.RoundnessEditField.Value = app.RND;
                app.PeakCountEditField.Value = app.pkNo_STR;
                app.TextEditField.Value = app.sen_STR;
                app.x = app.x+1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.live = uiaxes(app.UIFigure);
            title(app.live, 'Live')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.RoundnessEditFieldLabel = uilabel(app.UIFigure);
            app.RoundnessEditFieldLabel.Text = 'Roundness';
            app.RoundnessEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeakCountEditFieldLabel = uilabel(app.UIFigure);
            app.PeakCountEditFieldLabel.Text = 'Peak Count';
            app.PeakCountEditField = uieditfield(app.UIFigure, 'text');
            app.selectedArea = uiaxes(app.UIFigure);
            title(app.selectedArea, 'Selected Area')
            app.Jplot = uiaxes(app.UIFigure);
            title(app.Jplot, 'Plot')
            xlabel(app.Jplot, 'X')
            ylabel(app.Jplot, 'Y')
            app.background = uiaxes(app.UIFigure);
            title(app.background, 'Background')
            app.filteredOutput = uiaxes(app.UIFigure);
            title(app.filteredOutput, 'Filtered Output')
            app.LeaterEditFieldLabel = uilabel(app.UIFigure);
            app.LeaterEditFieldLabel.Text = 'Leater';
            app.LeaterEditField = uieditfield(app.UIFigure, 'text');
            app.TextEditFieldLabel = uilabel(app.UIFigure);
            app.TextEditFieldLabel.Text = 'Text';
            app.TextEditField = uieditfield(app.UIFigure, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = J1Pr43

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end